classdef app1_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        GenerateStatisticsButton  matlab.ui.control.Button
        ECGlagEditField           matlab.ui.control.NumericEditField
        ECGlagEditFieldLabel      matlab.ui.control.Label
        PPGlagEditField           matlab.ui.control.NumericEditField
        PPGlagEditFieldLabel      matlab.ui.control.Label
        DrawSyncECGButton         matlab.ui.control.Button
        DrawSyncPPGButton         matlab.ui.control.Button
        ECGFsEditField            matlab.ui.control.NumericEditField
        ECGFsEditFieldLabel       matlab.ui.control.Label
        PPGFsEditField            matlab.ui.control.NumericEditField
        PPGFsEditFieldLabel       matlab.ui.control.Label
        LoadCSFileButton          matlab.ui.control.Button
        LoadEDFHolterFileButton   matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        holterRR
        holterPeaks
        holterPeaks4Stats
        ppgRR
        ppgRR_unValid
        ppgPeaks
        ppgPks4Stats
        ecgPks4Stats
        ecgRR
        ecgRR_unValid
        ecgPeaks
        ppgLag = 0;
        ecgLag = 0;
        ppgSensitivity
        ppgFDR
        ppgError
        ppgFs = 64;
        ecgFs = 128;
        ecgSensitivity
        ecgFDR
        ecgError
        flagInds
        ecgFlagInds
        filename = 'NA';
        initPpgPeaks;
        initEcgPeaks;
        tol = 200;%130;
        ppgRho
        ecgRho
        ppgSlope
        ecgSlope
        ppgr2
        ecgr2
        ppgStats
        ecgStats
        path = 'C:\Users\';
        ppgStartFinish
        ecgStartFinish
        ecgRrSensitivity
        ecgRrFDR
        ppgRrSensitivity
        ppgRrFDR

        tPPG
        tEcg
        ECG_CS
        ppgPeaksInx
        Rpks
        leadOn

        ECG_Holter
        tHolter
        QRS_Holter

        mixedFlag
        PPG
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadEDFHolterFileButton
        function LoadEDFHolterFileButtonPushed(app, event)
            [file,app.path] = uigetfile('C:\Users\*.edf');
            [FsECG,QRS,ECG,hdr] = parseData(app.path,file,'EDF file');
            QRS(QRS>length(ECG)) = [];
            time = 1000*(0:1:length(ECG)-1)/FsECG;
            app.holterRR = diff(time(QRS));
            app.holterPeaks = time(QRS(2:end));

            app.ECG_Holter=ECG;
            app.tHolter=time;
            app.QRS_Holter=QRS;

            figure(app.UIFigure)
        end

        % Value changed function: PPGFsEditField
        function PPGFsEditFieldValueChanged(app, event)
            app.ppgFs = app.PPGFsEditField.Value;
            
        end

        % Value changed function: ECGFsEditField
        function ECGFsEditFieldValueChanged(app, event)
            app.ecgFs = app.ECGFsEditField.Value;
        end

        % Button pushed function: LoadCSFileButton
        function LoadCSFileButtonPushed(app, event)
            app.ppgLag = 0;
            app.ecgLag = 0;
            app.ECGlagEditField.Value = 0;
            app.PPGlagEditField.Value = 0;
            [file,path] = uigetfile([app.path,'\*.csv']);
            fs = app.ppgFs;
            ppgDelay = 19*fs + 34;
            opts = detectImportOptions(fullfile(path,file),'Delimiter',',','NumHeaderLines',0);
            opts = setvartype(opts,{'HRppg','HRecg'},'double');
            T = readtable(fullfile(path,file),opts);
            ppgPeaks = find(T.Vpeak)-T.Vpeak(T.Vpeak~=0)-ppgDelay+1;
            app.ppgRR = T.HRppg(find(T.Vpeak))*(1000/fs);
            extraFlag = zeros(size(T.Vpeak));
            hr4inds = nan(size(T.HRppg));
            hr4inds(ppgPeaks(ppgPeaks>0)) = app.ppgRR(ppgPeaks>0);
            app.ppgRR(ppgPeaks<1) = [];
            app.ppgRR_unValid=~app.ppgRR;       % Peaks relate to RR=0 are valid but their matched RR isnt (1=unvalid)
            
            ppgPeaks(ppgPeaks<1) = [];
            extraInds = find(hr4inds == 0);
            extraInds = [max(extraInds(:)-64,1),extraInds(:)];
            for k = 1:length(extraInds(:,1))
                extraFlag(extraInds(k,1):extraInds(k,2)) = 1;
            end
            [tPPG,tEcg] = tagTime(T.SamplingTime,app.ecgFs);
            tPPG = tPPG - tPPG(1);
            tEcg = tEcg - tEcg(1);

            app.tPPG=tPPG;
            app.tEcg=tEcg;
            app.ECG_CS=reshape([T.ECG_0,T.ECG_2]',height(T)*2,1);
            app.ppgPeaksInx=ppgPeaks;

            app.ppgStartFinish = [tPPG(1) tPPG(end)];
            app.ecgStartFinish = [tEcg(1) tEcg(end)];
            app.ppgPeaks = tPPG(ppgPeaks);
            app.initPpgPeaks = app.ppgPeaks;
            snrFlag = [T.Ppg(ppgDelay+1:end);zeros(ppgDelay,1)];
            accFlag = [T.Acc(ppgDelay+1:end);ones(ppgDelay,1)];
            artFlag = [T.Art(ppgDelay+1:end);ones(ppgDelay,1)];
            fpFlag = [T.PrePostFP(ppgDelay+1:end);ones(ppgDelay,1)];

            indicators = T.Indicators ~= 0;
            mixedFlag = logical(~snrFlag+accFlag+artFlag+fpFlag+indicators+T.Reset+extraFlag);
            mixedFlag = FlagBlockSpare(mixedFlag,20);
            mixedFlag(find(mixedFlag == 0,1,'first'):min(find(mixedFlag == 0,1,'first')+10*fs,length(mixedFlag))) = 1;
            mixedFlag(max(find(mixedFlag == 0,1,'last')-10*fs,1):find(mixedFlag == 0,1,'last')) = 1;
            app.mixedFlag=mixedFlag;
            app.PPG=T.PPG;
            app.flagInds = tPPG(find(mixedFlag));
            if isempty(app.flagInds), app.flagInds = app.ppgStartFinish; end
            [~,name,~] = fileparts(file);
            app.filename = name;
            HRecg=T.HRecg;
            ECGtime=T.ECGtime;
            Delay_ECG = app.ecgFs*2.5;
            [ind,~]=find(ECGtime>0);                    % place in CSV in which the peaks are located.
            %ind = ind(HRecg(ind)~=1);                   % Peaks relate to RR=1 aren't valid- printed for debug
            hrECG = HRecg(ind);                         % Conjugated heart rates.
            
            newPksECG =ind*2-ECGtime(ind);              % real peaks location.
            newPksECG=newPksECG-Delay_ECG+1;            % Overcoming phase.
            hrECG = hrECG(newPksECG>0);
            unValid_ecgRR=~hrECG;                       % Peaks relate to RR=0 are valid but their matched RR isnt (1=unvalid)
            
            app.ecgPeaks = tEcg(newPksECG(newPksECG>0));
            app.initEcgPeaks = app.ecgPeaks;
            app.ecgRR = 1000*(hrECG)/128;
            app.ecgRR_unValid=unValid_ecgRR;            % index
            app.Rpks=newPksECG(newPksECG>0);

            %flag for ecg?
            leadOn = zeros(size(T.Rate));
            leadOn(T.Rate ~=4) = 1;
            ecgQual = [T.ECGqualF(2.5*fs+1:end);zeros(2.5*fs,1)];
            leadOn(ecgQual == 0) = 1;
            leadOn=FlagBlockSpare(leadOn,20);
            leadOn = interp1((0:length(leadOn)-1)/fs,leadOn,(0:length(leadOn)*2-1)/app.ecgFs,'previous');
            leadOn(find(leadOn == 0,1,'first'):min(find(leadOn == 0,1,'first')+10*app.ecgFs,length(leadOn))) = 1;
            leadOn(max(find(leadOn == 0,1,'last')-10*app.ecgFs,1):find(leadOn == 0,1,'last')) = 1;
            app.leadOn=leadOn;
            app.ecgFlagInds = tEcg(find(leadOn));
            if isempty(app.ecgFlagInds), app.ecgFlagInds = app.ecgStartFinish; end
            figure(app.UIFigure)
        end

        % Button pushed function: DrawSyncPPGButton
        function DrawSyncPPGButtonPushed(app, event)
            
            % Plot a line and points
            app.UIFigure;
            grid(app.UIAxes,'on');
            grid(app.UIAxes,'minor');
            p1=plot(app.UIAxes,app.ppgPeaks,app.ppgRR,'-o','buttondownfcn',{@PPG_Mouse_Callback,'down'},'DisplayName','PPG RR Intervals');
            hold(app.UIAxes,'on')
            p2=plot(app.UIAxes,app.holterPeaks,app.holterRR,'-x','DisplayName','Holter RR Intervals');
            hold(app.UIAxes,'off')
            legend(app.UIAxes,'show');
            
            p3=plot(app.UIAxes2,p1.XData,100*ones(size(p1.XData)),'o'); 
            hold(app.UIAxes2,'on');
            p4=plot(app.UIAxes2,p2.XData,100*ones(size(p2.XData)),'X');
            hold(app.UIAxes2,'off');
            linkaxes([app.UIAxes2,app.UIAxes],'x');

            % Callback function for each point
            function PPG_Mouse_Callback(hObj,~,action)
                persistent curobj xdata ydata ind
                pos = get(app.UIAxes,'CurrentPoint');
                switch action
                    case 'down'
                        curobj = hObj;
                        xdata = get(hObj,'xdata');
                        ydata = get(hObj,'ydata');
                        [~,ind] = min(sum((xdata-pos(1)).^2+(ydata-pos(3)).^2,1));
                        set(app.UIFigure,...
                            'WindowButtonMotionFcn',  {@PPG_Mouse_Callback,'move'},...
                            'WindowButtonUpFcn',      {@PPG_Mouse_Callback,'up'});
                    case 'move'
                        % horizontal move
                        %                         lag = lag + pos(2)-xdata(ind);
                        app.ppgLag = app.ppgLag + round(pos(2)-xdata(ind));
                        xdata = xdata + round(pos(2) - xdata(ind));
                        app.ppgPeaks = xdata;
                        set(curobj,'xdata',xdata)
                        app.PPGlagEditField.Value = app.ppgLag;

                        p3.XData=xdata;
                    case 'up'
                        set(app.UIFigure,...
                            'WindowButtonMotionFcn',  '',...
                            'WindowButtonUpFcn',      '');
                end
            end
        end

        % Button pushed function: GenerateStatisticsButton
        function GenerateStatisticsButtonPushed(app, event)


            figure;
            lag=app.ecgLag;
            h(1)=subplot(3,1,1);
            plot(app.tEcg+lag,app.ECG_CS); hold on; title('CS ECG')
            plot(app.tEcg(app.Rpks)+lag,app.ECG_CS(app.Rpks),'*k');
            plot(app.tEcg+lag,app.leadOn*max(app.ECG_CS),'--r','DisplayName','noise flag');
            plot(app.tEcg(app.Rpks(app.ecgRR==1000/128))+lag,app.ECG_CS(app.Rpks(app.ecgRR==1000/128)),'or','DisplayName','RR=1, used as noise');
            legend show
            h(2)=subplot(3,1,2);
            plot(app.tHolter,app.ECG_Holter); hold on; title('Holter')
            plot(app.tHolter(app.QRS_Holter),app.ECG_Holter(app.QRS_Holter),'*k');
            h(3)=subplot(3,1,3);
            lag=app.ppgLag;
            plot(app.tPPG+lag,app.PPG); hold on; title('PPG')
            plot(app.tPPG(app.ppgPeaksInx)+lag,app.PPG(app.ppgPeaksInx),'*');
            plot(app.tPPG+lag,app.mixedFlag*max(app.PPG),'--r');
            linkaxes(h,'x');

            
            ecgRes = ceil(1000*(1/app.ecgFs));
            ppgRes = ceil(1000*(1/app.ppgFs));
            %move Peaks - ppg
            app.ppgPks4Stats = app.ppgPeaks;
            flagInds4Stats = app.flagInds + app.ppgLag;
            ppgStartFinish = app.ppgStartFinish + app.ppgLag;
            %move pks - ecg
            app.ecgPks4Stats = app.ecgPeaks;
            ecgFlagInds4Stats = app.ecgFlagInds + app.ecgLag;
            ecgStartFinish = app.ecgStartFinish + app.ecgLag;
            % local RRs
            ppgRR = app.ppgRR;
            ppgRR_unValid = app.ppgRR_unValid;
            ecgRR = app.ecgRR;
            ecgRR_unValid = app.ecgRR_unValid;
            % where to start cutting
            if abs(flagInds4Stats(1) - ppgStartFinish(1))> ppgRes, start = ppgStartFinish(1); else, start = flagInds4Stats(1);end
            if abs(flagInds4Stats(end) - ppgStartFinish(end))> ppgRes, fin = ppgStartFinish(end); else, fin = flagInds4Stats(end);end
            %remove edges
            [startTime,indStart] = max([start-ppgRes,app.holterPeaks(1)]); %first time no flag
            [endTime,indEnd] = min([fin+ppgRes,app.holterPeaks(end)]); %last time no flag
            app.holterPeaks4Stats = app.holterPeaks;
            if indStart ==1
                app.holterPeaks4Stats = app.holterPeaks4Stats(app.holterPeaks4Stats>=startTime-app.tol);
            else
                ppgRR = ppgRR(app.ppgPks4Stats>=startTime-app.tol);
                ppgRR_unValid = ppgRR_unValid(app.ppgPks4Stats>=startTime-app.tol);
                app.ppgPks4Stats = app.ppgPks4Stats(app.ppgPks4Stats>=startTime-app.tol);
            end
            if indEnd ==1
                app.holterPeaks4Stats = app.holterPeaks4Stats(app.holterPeaks4Stats<=endTime+app.tol);
            else
                ppgRR = ppgRR(app.ppgPks4Stats<=endTime+app.tol);
                ppgRR_unValid = ppgRR_unValid(app.ppgPks4Stats<=endTime+app.tol);
                app.ppgPks4Stats = app.ppgPks4Stats(app.ppgPks4Stats<=endTime+app.tol);
            end
            %PPG Stats
            %lagFixPPG = snap2data(app.holterPeaks4Stats,app.ppgPks4Stats);
            lagFixPPG=0;
            [app.ppgError,app.ppgSensitivity,app.ppgRrSensitivity,app.ppgFDR,app.ppgRrFDR, app.ppgRho,app.ppgSlope,app.ppgr2,pkData] = peaksBasedPerformanceAnalysis(app.holterPeaks4Stats,app.ppgPks4Stats-lagFixPPG,ppgRR,ppgRR_unValid,app.tol,flagInds4Stats-lagFixPPG,app.ppgLag+lagFixPPG,'PPG STATS');
            app.ppgStats = [table(repmat({app.filename},height(pkData),1),'VariableNames',{'fileName'}),pkData];
            if sign(app.ppgLag)>0, sgn = 'P'; else, sgn = 'N'; end
            app.ppgStats.Properties.VariableNames = {'fileNumber',...
                ['PPG_time_ms_',num2str(app.ppgFs)],...
                ['Original_PPG_time_ms_',sgn,'_',num2str(app.ppgLag+lagFixPPG)],...
                'PPG_RR_ms', 'PPG_Peak','Holter_Noisy','Holter_time_ms',...
                'Holter_RR_ms','Holter_Peak','PPG_Noisy'};
            % where to start cutting
            if abs(ecgFlagInds4Stats(1) - ecgStartFinish(1))> ecgRes, start = ecgStartFinish(1); else, start = ecgFlagInds4Stats(1);end
            if abs(ecgFlagInds4Stats(end) - ecgStartFinish(end))> ecgRes, fin = ecgStartFinish(end); else, fin = ecgFlagInds4Stats(end);end
      
            %remove edges
            [startTime,indStart] = max([start-ecgRes,app.holterPeaks(1)]);
            [endTime,indEnd] = min([fin+ecgRes,app.holterPeaks(end)]);
            app.holterPeaks4Stats = app.holterPeaks;
            if indStart ==1
                app.holterPeaks4Stats = app.holterPeaks4Stats(app.holterPeaks4Stats>=startTime-app.tol);
            else
                ecgRR = ecgRR(app.ecgPks4Stats>=startTime-app.tol);
                ecgRR_unValid = ecgRR_unValid(app.ecgPks4Stats>=startTime-app.tol);
                app.ecgPks4Stats = app.ecgPks4Stats(app.ecgPks4Stats>=startTime-app.tol);
            end
            if indEnd ==1
                app.holterPeaks4Stats = app.holterPeaks4Stats(app.holterPeaks4Stats<=endTime+app.tol);
            else
                ecgRR = ecgRR(app.ecgPks4Stats<=endTime+app.tol);
                ecgRR_unValid = ecgRR_unValid(app.ecgPks4Stats<=endTime+app.tol);
                app.ecgPks4Stats = app.ecgPks4Stats(app.ecgPks4Stats<=endTime+app.tol);
            end
            % ECG Stats
            %lagFixECG = snap2data(app.holterPeaks4Stats,app.ecgPks4Stats);
            lagFixECG=0;
            [app.ecgError,app.ecgSensitivity, app.ecgRrSensitivity,app.ecgFDR,app.ecgRrFDR, app.ecgRho,app.ecgSlope,app.ecgr2,pkData] = peaksBasedPerformanceAnalysis(app.holterPeaks4Stats,app.ecgPks4Stats-lagFixECG,ecgRR,ecgRR_unValid,app.tol,ecgFlagInds4Stats-lagFixECG,app.ecgLag+lagFixECG,'ECG STATS');
            app.ecgStats = [table(repmat({app.filename},height(pkData),1),'VariableNames',{'fileName'}),pkData];
            if sign(app.ecgLag)>0, sgn = 'P'; else, sgn = 'N'; end
            app.ecgStats.Properties.VariableNames = {'fileNumber',...
                ['ECG_time_ms_',num2str(app.ppgFs)],...
                ['Original_ECG_time_ms_',sgn,'_',num2str(app.ecgLag+lagFixECG)],...
                'ECG_RR_ms', 'ECG_Peak','Holter_Noisy','Holter_time_ms',...
                'Holter_RR_ms','Holter_Peak','ECG_Noisy'};
            %write stats
            T = table({app.filename}, app.ppgError,app.ppgSensitivity, app.ppgRrSensitivity,app.ppgFDR, app.ppgRrFDR...
                ,app.ppgRho,app.ppgSlope,app.ppgr2,app.ecgError,app.ecgSensitivity,app.ecgRrSensitivity, app.ecgFDR, app.ecgRrFDR...
                ,app.ecgRho,app.ecgSlope,app.ecgr2,'VariableNames',{'File','PPG RMS BPM','PPG Sensitivity','PPG RR Sensitivity'...
                'PPG FDR','PPG RR FDR','PPG Pearson','PPG Fit Slope','PPG Fit R2','ECG RMS BPM','ECG Sensitivity', 'ECG RR Sensitivity'...
                'ECG FDR','ECG RR FDR','ECG Pearson','ECG Fit Slope','ECG Fit R2'});
            [file,path] = uiputfile([app.path,'\stats_report_',app.filename,'.csv']);
            comPath= fullfile(path,file);
            writetable(T,comPath);
            writetable(app.ppgStats,fullfile(path,[app.filename,'-stat-reportPPG.csv']));
            writetable(app.ecgStats,fullfile(path,[app.filename,'-stat-reportECG.csv']))
            %zero mutable vars
            app.holterPeaks4Stats = [];
            app.ecgPks4Stats = [];
            app.ppgPks4Stats = [];
        end

        % Button pushed function: DrawSyncECGButton
        function DrawSyncECGButtonPushed(app, event)
            
            % Plot a line and points
            app.UIFigure;
            grid(app.UIAxes,'on');
            grid(app.UIAxes,'minor');
            RR2plot=app.ecgRR;
            RR2plot(app.ecgRR_unValid)=nan;
            p1=plot(app.UIAxes,app.ecgPeaks,RR2plot,'-o','buttondownfcn',{@ECG_Mouse_Callback,'down'},'DisplayName','ECG RR Intervals');
            hold(app.UIAxes,'on')
            p2=plot(app.UIAxes,app.holterPeaks,app.holterRR,'-x','DisplayName','Holter RR Intervals');
            hold(app.UIAxes,'off')
            legend(app.UIAxes,'show');
            
            p3=plot(app.UIAxes2,p1.XData,100*ones(size(p1.XData)),'o'); 
            hold(app.UIAxes2,'on');
            p4=plot(app.UIAxes2,p2.XData,100*ones(size(p2.XData)),'X');
            hold(app.UIAxes2,'off');
            linkaxes([app.UIAxes2,app.UIAxes],'x');
            
            % Callback function for each point
            function ECG_Mouse_Callback(hObj,~,action)
                persistent curobj xdata ydata ind
                pos = get(app.UIAxes,'CurrentPoint');
                switch action
                    case 'down'
                        curobj = hObj;
                        xdata = get(hObj,'xdata');
                        ydata = get(hObj,'ydata');
                        [~,ind] = min(sum((xdata-pos(1)).^2+(ydata-pos(3)).^2,1));
                        set(app.UIFigure,...
                            'WindowButtonMotionFcn',  {@ECG_Mouse_Callback,'move'},...
                            'WindowButtonUpFcn',      {@ECG_Mouse_Callback,'up'});
                    case 'move'
                        % horizontal move
                        
                        %                         lag = lag + pos(2)-xdata(ind);
                        app.ecgLag = app.ecgLag + round(pos(2)-xdata(ind));
                        app.ECGlagEditField.Value = app.ecgLag;
                        xdata = xdata + round(pos(2) - xdata(ind));
                        app.ecgPeaks = xdata;
                        set(curobj,'xdata',xdata)

                        p3.XData=xdata;
                    case 'up'
                        set(app.UIFigure,...
                            'WindowButtonMotionFcn',  '',...
                            'WindowButtonUpFcn',      '');
                end
            end
        end

        % Value changed function: PPGlagEditField
        function PPGlagEditFieldValueChanged(app, event)
            app.ppgLag = app.PPGlagEditField.Value;
            app.ppgPeaks = app.initPpgPeaks + app.ppgLag;
            clear PPG_Mouse_Callback;
            DrawSyncPPGButtonPushed(app, event);
        end

        % Value changed function: ECGlagEditField
        function ECGlagEditFieldValueChanged(app, event)
            app.ecgLag = app.ECGlagEditField.Value;
            app.ecgPeaks = app.initEcgPeaks + app.ecgLag;
            clear PPG_Mouse_Callback;
            DrawSyncECGButtonPushed(app, event);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadEDFHolterFileButton = uibutton(app.LeftPanel, 'push');
            app.LoadEDFHolterFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEDFHolterFileButtonPushed, true);
            app.LoadEDFHolterFileButton.Text = 'Load EDF Holter File';
            app.LoadCSFileButton = uibutton(app.LeftPanel, 'push');
            app.LoadCSFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCSFileButtonPushed, true);
            app.LoadCSFileButton.Text = 'Load CS File';
            app.PPGFsEditFieldLabel = uilabel(app.LeftPanel);
            app.PPGFsEditFieldLabel.Text = 'PPG Fs';
            app.PPGFsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PPGFsEditField.ValueChangedFcn = createCallbackFcn(app, @PPGFsEditFieldValueChanged, true);
            app.PPGFsEditField.Value = 64;
            app.ECGFsEditFieldLabel = uilabel(app.LeftPanel);
            app.ECGFsEditFieldLabel.Text = 'ECG Fs';
            app.ECGFsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ECGFsEditField.ValueChangedFcn = createCallbackFcn(app, @ECGFsEditFieldValueChanged, true);
            app.ECGFsEditField.Value = 128;
            app.DrawSyncPPGButton = uibutton(app.LeftPanel, 'push');
            app.DrawSyncPPGButton.ButtonPushedFcn = createCallbackFcn(app, @DrawSyncPPGButtonPushed, true);
            app.DrawSyncPPGButton.Text = 'Draw & Sync PPG';
            app.DrawSyncECGButton = uibutton(app.LeftPanel, 'push');
            app.DrawSyncECGButton.ButtonPushedFcn = createCallbackFcn(app, @DrawSyncECGButtonPushed, true);
            app.DrawSyncECGButton.Text = 'Draw & Sync ECG';
            app.PPGlagEditFieldLabel = uilabel(app.LeftPanel);
            app.PPGlagEditFieldLabel.Text = 'PPG lag';
            app.PPGlagEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PPGlagEditField.ValueChangedFcn = createCallbackFcn(app, @PPGlagEditFieldValueChanged, true);
            app.ECGlagEditFieldLabel = uilabel(app.LeftPanel);
            app.ECGlagEditFieldLabel.Text = 'ECG lag';
            app.ECGlagEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ECGlagEditField.ValueChangedFcn = createCallbackFcn(app, @ECGlagEditFieldValueChanged, true);
            app.GenerateStatisticsButton = uibutton(app.LeftPanel, 'push');
            app.GenerateStatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateStatisticsButtonPushed, true);
            app.GenerateStatisticsButton.Text = 'Generate Statistics';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'RR Sync Plot')
            xlabel(app.UIAxes, 'Peak Time Stamp [ms]')
            ylabel(app.UIAxes, 'RR [ms]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end